classdef ulgGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadFilesPanel              matlab.ui.container.Panel
        Load                        matlab.ui.control.Button
        ReadyLamp_2Label            matlab.ui.control.Label
        StatsReady                  matlab.ui.control.Lamp
        OptionsPanel                matlab.ui.container.Panel
        Exit                        matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        StatsPanel                  matlab.ui.container.Panel
        Max_u                       matlab.ui.control.Label
        Max_v                       matlab.ui.control.Label
        Max_alt                     matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        PositionTab                 matlab.ui.container.Tab
        Alt                         matlab.ui.control.Button
        XYZ                         matlab.ui.control.Button
        TargetPanel                 matlab.ui.container.Panel
        xmEditFieldLabel            matlab.ui.control.Label
        Target_x                    matlab.ui.control.EditField
        ymLabel                     matlab.ui.control.Label
        Target_y                    matlab.ui.control.EditField
        zmLabel                     matlab.ui.control.Label
        Target_z                    matlab.ui.control.EditField
        VelocityTab                 matlab.ui.container.Tab
        Vel                         matlab.ui.control.Button
        AccelerationTab             matlab.ui.container.Tab
        Accel                       matlab.ui.control.Button
        LowPassFilterCutOffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LPFFreq                     matlab.ui.control.EditField
        SetButton                   matlab.ui.control.Button
        ReadyLampLabel              matlab.ui.control.Label
        ReadyLamp                   matlab.ui.control.Lamp
        TgoTab                      matlab.ui.container.Tab
        TargetPosexmEditFieldLabel  matlab.ui.control.Label
        Tx                          matlab.ui.control.NumericEditField
        TargetPoseymLabel           matlab.ui.control.Label
        Ty                          matlab.ui.control.NumericEditField
        TargetPosezmLabel           matlab.ui.control.Label
        Tz                          matlab.ui.control.NumericEditField
        TargetVelxmLabel            matlab.ui.control.Label
        Tvx                         matlab.ui.control.NumericEditField
        TargetVelymLabel            matlab.ui.control.Label
        Tvy                         matlab.ui.control.NumericEditField
        TargetVelzmLabel            matlab.ui.control.Label
        Tvz                         matlab.ui.control.NumericEditField
        rhoUms2EditFieldLabel       matlab.ui.control.Label
        RhoU                        matlab.ui.control.EditField
        rhoWms2EditFieldLabel       matlab.ui.control.Label
        RhoW                        matlab.ui.control.EditField
        mmEditFieldLabel            matlab.ui.control.Label
        M                           matlab.ui.control.EditField
        minTgosEditFieldLabel       matlab.ui.control.Label
        MinTgo                      matlab.ui.control.EditField
        PlotTgo                     matlab.ui.control.Button
        PlotRange                   matlab.ui.control.Button
        ChooseIntervalButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ulg_file % Description
        MATname
        max_u
        max_v
        max_h
        xmin
        xmax
    end
    
    methods (Access = private)
        
        function fileName = corretName(~,ulg_file)
            % The log file name, no file specifier required
            fileName_check = ulg_file;
            dotLocations = find(fileName_check == '.');
            if isempty(dotLocations)
               	% No dots at all found so just take entire name.
               	fileName = ulg_file;
            else
               	% Take up to , but not including, the first dot.
               	fileName = ulg_file(1:dotLocations(1)-1);
            end
            
        end
        
        function matName = addMATextention(~,ulg_file)
            matName = sprintf('%s.mat',ulg_file);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.ulg_file = uigetfile('*.ulg');
            app.ulg_file = corretName(app,app.ulg_file);
            app.MATname = addMATextention(app,app.ulg_file);
            ulg2mat(app.ulg_file);
            [app.max_v,app.max_u,app.max_h] = getStats(app.MATname);
            str_v = sprintf('Maximum Velocity is %.3f [m/s]',app.max_v);
            str_u = sprintf('Maximum Acceleration is %.3f [m/s^2]',app.max_u);
            str_h = sprintf('Maximum Hight is %.3f [m]',app.max_h);
            app.Max_v.Text = str_v;
            app.Max_u.Text = str_u;
            app.Max_alt.Text = str_h;
            app.StatsReady.Color = 'green';
        end

        % Button pushed function: XYZ
        function XYZButtonPushed(app, event)
            xt = str2double(app.Target_x.Value);
            yt = str2double(app.Target_y.Value);
            zt = str2double(app.Target_z.Value);
            plotXYZ(app.MATname,xt,yt,zt);
        end

        % Button pushed function: Alt
        function AltButtonPushed(app, event)
            plotAlt(app.MATname);
        end

        % Button pushed function: Accel
        function AccelButtonPushed(app, event)
            plotAccel(app.MATname,str2double(app.LPFFreq.Value));
        end

        % Button pushed function: Vel
        function VelButtonPushed(app, event)
            plotVel(app.MATname);
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ReadyLamp.Color = 'green';
        end

        % Button pushed function: PlotTgo
        function PlotTgoButtonPushed(app, event)
            R_e = [app.Tx.Value app.Ty.Value app.Tz.Value];
            V_e = [app.Tvx.Value app.Tvy.Value app.Tvz.Value];
            
            rho_u = str2double(app.RhoU.Value);
            rho_w = str2double(app.RhoW.Value);
            
            m = str2double(app.M.Value);
            
            minTgo = str2double(app.MinTgo.Value);
            
            plotTgo(app.MATname,rho_u,rho_w,m,minTgo,R_e,V_e);
        end

        % Button pushed function: PlotRange
        function PlotRangeButtonPushed(app, event)
            R_e = [app.Tx.Value app.Ty.Value app.Tz.Value];
            V_e = [app.Tvx.Value app.Tvy.Value app.Tvz.Value];
            plotRange(app.MATname,R_e,V_e,0)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ulg_file = '';
            app.MATname = '';
            app.max_h = 0;
            app.max_u = 0;
            app.max_v = 0;
            app.Max_alt = 0;
        end

        % Button pushed function: ChooseIntervalButton
        function ChooseIntervalButtonPushed(app, event)
            [app.xmax,~] = ginput(1);
            R_e = [app.Tx.Value app.Ty.Value app.Tz.Value];
            V_e = [app.Tvx.Value app.Tvy.Value app.Tvz.Value];
            plotRange(app.MATname,R_e,V_e,app.xmax)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadFilesPanel = uipanel(app.UIFigure);
            app.LoadFilesPanel.Title = 'Load Files';
            app.Load = uibutton(app.LoadFilesPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load .ulg File';
            app.ReadyLamp_2Label = uilabel(app.LoadFilesPanel);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.StatsReady = uilamp(app.LoadFilesPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Exit = uibutton(app.OptionsPanel, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.ResetButton = uibutton(app.OptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatsPanel = uipanel(app.UIFigure);
            app.StatsPanel.Title = 'Stats';
            app.Max_u = uilabel(app.StatsPanel);
            app.Max_u.Text = '';
            app.Max_v = uilabel(app.StatsPanel);
            app.Max_v.Text = '';
            app.Max_alt = uilabel(app.StatsPanel);
            app.Max_alt.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'px4_logo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.Alt = uibutton(app.PositionTab, 'push');
            app.Alt.ButtonPushedFcn = createCallbackFcn(app, @AltButtonPushed, true);
            app.Alt.Text = 'Plot Altitude from Baro';
            app.XYZ = uibutton(app.PositionTab, 'push');
            app.XYZ.ButtonPushedFcn = createCallbackFcn(app, @XYZButtonPushed, true);
            app.XYZ.Text = 'Plot X-Y-Z from GPS';
            app.TargetPanel = uipanel(app.PositionTab);
            app.TargetPanel.Title = 'Target ';
            app.xmEditFieldLabel = uilabel(app.TargetPanel);
            app.xmEditFieldLabel.Text = 'x [m]';
            app.Target_x = uieditfield(app.TargetPanel, 'text');
            app.Target_x.Value = '50';
            app.ymLabel = uilabel(app.TargetPanel);
            app.ymLabel.Text = 'y [m]';
            app.Target_y = uieditfield(app.TargetPanel, 'text');
            app.Target_y.Value = '50';
            app.zmLabel = uilabel(app.TargetPanel);
            app.zmLabel.Text = 'z [m]';
            app.Target_z = uieditfield(app.TargetPanel, 'text');
            app.Target_z.Value = '50';
            app.VelocityTab = uitab(app.TabGroup);
            app.VelocityTab.Title = 'Velocity';
            app.Vel = uibutton(app.VelocityTab, 'push');
            app.Vel.ButtonPushedFcn = createCallbackFcn(app, @VelButtonPushed, true);
            app.Vel.Text = 'Plot Velocities from GPS ';
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.Accel = uibutton(app.AccelerationTab, 'push');
            app.Accel.ButtonPushedFcn = createCallbackFcn(app, @AccelButtonPushed, true);
            app.Accel.Text = 'Plot Acceleration Data ';
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel = uilabel(app.AccelerationTab);
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel.Text = 'Low-Pass Filter Cut-Off Frequency [Hz] =';
            app.LPFFreq = uieditfield(app.AccelerationTab, 'text');
            app.SetButton = uibutton(app.AccelerationTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ReadyLampLabel = uilabel(app.AccelerationTab);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.AccelerationTab);
            app.TgoTab = uitab(app.TabGroup);
            app.TgoTab.Title = 'Tgo';
            app.TargetPosexmEditFieldLabel = uilabel(app.TgoTab);
            app.TargetPosexmEditFieldLabel.Text = 'Target Pose - x [m] =';
            app.Tx = uieditfield(app.TgoTab, 'numeric');
            app.Tx.Value = 50;
            app.TargetPoseymLabel = uilabel(app.TgoTab);
            app.TargetPoseymLabel.Text = 'Target Pose - y [m] =';
            app.Ty = uieditfield(app.TgoTab, 'numeric');
            app.Ty.Value = 50;
            app.TargetPosezmLabel = uilabel(app.TgoTab);
            app.TargetPosezmLabel.Text = 'Target Pose - z [m] =';
            app.Tz = uieditfield(app.TgoTab, 'numeric');
            app.Tz.Value = 50;
            app.TargetVelxmLabel = uilabel(app.TgoTab);
            app.TargetVelxmLabel.Text = 'Target Vel - x [m] =';
            app.Tvx = uieditfield(app.TgoTab, 'numeric');
            app.TargetVelymLabel = uilabel(app.TgoTab);
            app.TargetVelymLabel.Text = 'Target Vel - y [m] =';
            app.Tvy = uieditfield(app.TgoTab, 'numeric');
            app.TargetVelzmLabel = uilabel(app.TgoTab);
            app.TargetVelzmLabel.Text = 'Target Vel - z [m] =';
            app.Tvz = uieditfield(app.TgoTab, 'numeric');
            app.rhoUms2EditFieldLabel = uilabel(app.TgoTab);
            app.rhoUms2EditFieldLabel.Text = 'rhoU [m/s^2]';
            app.RhoU = uieditfield(app.TgoTab, 'text');
            app.RhoU.Value = '20';
            app.rhoWms2EditFieldLabel = uilabel(app.TgoTab);
            app.rhoWms2EditFieldLabel.Text = 'rhoW [m/s^2]';
            app.RhoW = uieditfield(app.TgoTab, 'text');
            app.RhoW.Value = '9.81';
            app.mmEditFieldLabel = uilabel(app.TgoTab);
            app.mmEditFieldLabel.Text = 'm [m]';
            app.M = uieditfield(app.TgoTab, 'text');
            app.M.Value = '1.5';
            app.minTgosEditFieldLabel = uilabel(app.TgoTab);
            app.minTgosEditFieldLabel.Text = 'minTgo [s]';
            app.MinTgo = uieditfield(app.TgoTab, 'text');
            app.MinTgo.Value = '0.5';
            app.PlotTgo = uibutton(app.TgoTab, 'push');
            app.PlotTgo.ButtonPushedFcn = createCallbackFcn(app, @PlotTgoButtonPushed, true);
            app.PlotTgo.Text = 'Plot Tgo vs Time';
            app.PlotRange = uibutton(app.TgoTab, 'push');
            app.PlotRange.ButtonPushedFcn = createCallbackFcn(app, @PlotRangeButtonPushed, true);
            app.PlotRange.Text = 'Plot Range vs Time';
            app.ChooseIntervalButton = uibutton(app.TgoTab, 'push');
            app.ChooseIntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseIntervalButtonPushed, true);
            app.ChooseIntervalButton.Text = 'Choose Interval';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ulgGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end